classdef projekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OryginalnyobrazLabel            matlab.ui.control.Label
        ObrazposegmentacjibarwnejLabel  matlab.ui.control.Label
        loadButton                      matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        ParametrSliderLabel             matlab.ui.control.Label
        ParamSlider                     matlab.ui.control.Slider
        SegmentButton                   matlab.ui.control.Button
        UIimgSegmented                  matlab.ui.control.UIAxes
        UIImgOriginal                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgOriginal % matrix that stores original image
        imgSegmented % matrix for segmentation operations
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function LoadImage(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
            app.imgOriginal = imread(fullname);
            imshow(app.imgOriginal, 'Parent', app.UIImgOriginal);
        end

        % Button pushed function: saveButton
        function SaveImage(app, event)
            % Create a temporary figure with axes.
%             fig = figure;
%             fig.Visible = 'off';
%             figAxes = axes(fig);
%             % Copy all UIAxes children, take over axes limits and aspect ratio.            
%             allChildren = app.UIAxes.XAxis.Parent.Children;
%             
%             copyobj(app.UIImgOriginal, figAxes)
%             % Save as png and fig files.
%             saveas(fig, fileName, 'png');
%             savefig(fig, fileName);
%             % Delete the temporary figure.
%             delete(fig);
            %dialog box,save as
            filter = {'*.jpg';'*.png'};
            [filename, pathname] = uiputfile(filter);
            newfilename = fullfile(pathname, filename);
            imwrite(app.imgOriginal, newfilename); % tu powinno byc imgSegmented (narazie tak dla testow dalam imgOriginal)
        end

        % Value changed function: ParamSlider
        function ChangeParameter(app, event)
            %value = app.ParamSlider.Value;
            
        end

        % Button pushed function: SegmentButton
        function Segmentation(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OryginalnyobrazLabel = uilabel(app.UIFigure);
            app.OryginalnyobrazLabel.Text = 'Oryginalny obraz';
            app.ObrazposegmentacjibarwnejLabel = uilabel(app.UIFigure);
            app.ObrazposegmentacjibarwnejLabel.Text = 'Obraz po segmentacji barwnej';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImage, true);
            app.loadButton.Text = 'Wczytaj obraz';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImage, true);
            app.saveButton.Text = 'Zapisz obraz';
            app.ParametrSliderLabel = uilabel(app.UIFigure);
            app.ParametrSliderLabel.Text = 'Parametr';
            app.ParamSlider = uislider(app.UIFigure);
            app.ParamSlider.ValueChangedFcn = createCallbackFcn(app, @ChangeParameter, true);
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @Segmentation, true);
            app.SegmentButton.Text = 'Segmentacja';
            app.UIimgSegmented = uiaxes(app.UIFigure);
            zlabel(app.UIimgSegmented, 'Z')
            app.UIimgSegmented.XTick = [];
            app.UIimgSegmented.YTickLabel = '';
            app.UIImgOriginal = uiaxes(app.UIFigure);
            zlabel(app.UIImgOriginal, 'Z')
            app.UIImgOriginal.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIImgOriginal.XTickLabel = '';
            app.UIImgOriginal.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIImgOriginal.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projekt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end